classdef Crosscorrelation_PS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        ToggleTool                      matlab.ui.container.toolbar.ToggleTool
        PushTool                        matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        IterationsSpinner               matlab.ui.control.Spinner
        IterationsSpinnerLabel          matlab.ui.control.Label
        IterateButton                   matlab.ui.control.Button
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        WindowIdxLabel                  matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        ModSigSizeLabel                 matlab.ui.control.Label
        SampleDataLabel                 matlab.ui.control.Label
        SampleInfoLabel                 matlab.ui.control.Label
        CorrelateButton                 matlab.ui.control.Button
        LossdBSpinner                   matlab.ui.control.Spinner
        LossdBSpinnerLabel              matlab.ui.control.Label
        NoisedBSpinner                  matlab.ui.control.Spinner
        NoisedBSpinnerLabel             matlab.ui.control.Label
        WindowSlider                    matlab.ui.control.RangeSlider
        WindowSliderLabel               matlab.ui.control.Label
        DelayUnitSpinner                matlab.ui.control.Spinner
        DelayUnitSpinnerLabel           matlab.ui.control.Label
        SignalListBox                   matlab.ui.control.ListBox
        BaseSignalLabel                 matlab.ui.control.Label
        CrossCorrelationSimulatorLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

% Need to implement channel handling, need 3 channel for location
% Need saving data /screenshot option
% Channel options are off, primarly use ch1
% need envelop signal and static noise
% Version 0.9
% New Features branch
% extra notes
% requires new function, iterations x1000, save results
% need simulation in progress bar, because it takes a lots of time
% file need to be saved in a specific folder
% screenshot of the setting need to be saved in the folder
% axes need to be saved as well
% Version 3.0
% only the iterate button shows the processing, but it is enough
% need to implement the iteration number


    properties (Access = private)
        Sig % Signal Data from wav file
        Fs % Sample Rate from wav file
        Mod_Sig % modified signal
        Noisy_Sig % generated noise according to the signal amplitude
        Sig_rows % signal rows
        Sig_cols % signal columns
        Sig_window % Windowed signal
        Sig_info % audioinformation
        Mod_Sig_window % Window Modified signal
        LowerWindowLimit; % signal window limits
        UpperWindowLimit;
        Active_Ch_number; % selected channel of the wav file
        % N = 200; % Iteration number %does not require anymore
        Current_folder = pwd; % save the current folder router at the beginning of the app
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_signal(app,[]); % just initialization at start up
            app.WindowSlider.MajorTickLabels = app.WindowSlider.MajorTicks + "%"; % "%" to the windowslide bar, looks better
            app.WindowSlider.MinorTicks = 1:100;
            % addpath("C:\Users\peter\Desktop\Honours Project\Simulation"); % if the audio files are in different libraries
            app.ToggleTool.State = "on"; %sound on
            
            % Create a Screenshots library if not already exists
            librarycheck = app.Current_folder + "\Screenshots";
            if (exist(librarycheck, "dir") ~= 7)
                mkdir("Screenshots");
            end
        end

        % Callback function: SignalListBox, SignalListBox
        function load_signal(app, event)
            [app.Sig, app.Fs] = audioread(app.SignalListBox.Value); % according to the list value, which is a file name for easy use
            
            % audio information
            app.Sig_info = audioinfo(app.SignalListBox.Value);
            message = sprintf("Filename: %s\nTitle: %s\nArtist: %s\nComment: %s", app.SignalListBox.Value, app.Sig_info.Title, app.Sig_info.Artist , app.Sig_info.Comment);
            app.SampleInfoLabel.Text = message;
            [app.Sig_rows,app.Sig_cols] = size(app.Sig);  % checking the size of the signal
            message_2 = sprintf("Size: %d x %d \nType: %s\nSampleRate: %d\nTotalSamples: %d", app.Sig_rows, app.Sig_cols, class(app.Sig), app.Sig_info.SampleRate, app.Sig_info.TotalSamples);
            app.SampleDataLabel.Text = message_2;


            app.DelayUnitSpinner.Limits = [0, app.Sig_rows-1]; % Delay cannot be higher than the signal length
            app.Active_Ch_number = 1; % tracking the active channel
            
            plot(app.UIAxes,app.Sig);  % plot the original signal and clears the other two
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.LowerWindowLimit = round(app.Sig_rows * app.WindowSlider.Value(1) / 100) + 1;
            app.UpperWindowLimit = round(app.Sig_rows * app.WindowSlider.Value(2) / 100) + 1;
            xline(app.UIAxes, app.LowerWindowLimit); % needs the line according to the values
            xline(app.UIAxes, app.UpperWindowLimit);

        end

        % Double-clicked callback: SignalListBox
        function play_signal(app, event)
            if (app.Sig_cols < 3)   % sound function only able to play one and two channel wav files. 
                if (app.ToggleTool.State == "on") % sounds only, if the sound toggle button is on
                    sound(app.Sig,app.Fs);
                end
            end
        end

        % Callback function: CorrelateButton, UIAxes3
        function correlation(app, event)
            %cla(app.UIAxes3,"reset"); % clears the previous plot at axe and labels as well unfortunately. does not require.
            % wrong order cause opposite signed value in xcorr !!!
            [cor,lags]=xcorr(app.Noisy_Sig(:,app.Active_Ch_number), app.Sig(app.LowerWindowLimit:app.UpperWindowLimit-1,app.Active_Ch_number)); % correlations calculations for channel one, need to be parameterized
            [mval,idx] = max(abs(cor)); % index of the maximum value of the correlation    
            plot(app.UIAxes3,lags,cor); % plot the correlations result, need more work on this, where the max, labels, more visualization, etc...
            ytickformat(app.UIAxes3,"auto");
            ylabel(app.UIAxes3,"Correlation Coefficient");

            % Highlight the maximum correlation point
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, lags(idx), mval,'ro', 'MarkerSize', 10);
            text(app.UIAxes3, lags(idx), mval, sprintf("\n               Coeff = %d",  round(mval)), "HorizontalAlignment", "left");
            text(app.UIAxes3, lags(idx), mval, sprintf("\nLag = %d               ", lags(idx)), "HorizontalAlignment", "right");
            hold(app.UIAxes3, 'off');

            strcorx = "Lag: " + lags(idx); % some correlation number information at the side
            strcory = "Coeff: " + round(cor(idx)); %just want to see some roundy numbers
            app.XLabel.Text = strcorx; % turning on the lag and coeff labels
            app.XLabel.Visible = "on";
            app.YLabel.Text = strcory;
            app.YLabel.Visible = "on";
            app.IterateButton.Visible = "on";
            app.IterationsSpinner.Visible = "on";
            app.IterationsSpinnerLabel.Visible = "on";

        end

        % Value changed function: WindowSlider
        function windowlimits(app, event)
            % This caused the problem with the window function
            % app.LowerWindowLimit = value(1);
            % app.UpperWindowLimit = value(2);
            app.LowerWindowLimit = round(app.Sig_rows * app.WindowSlider.Value(1) / 100) + 1;
            app.UpperWindowLimit = round(app.Sig_rows * app.WindowSlider.Value(2) / 100) + 1;
            % maximum can reach the minimum and can cause problem, need
            % solution for it
        end

        % Value changing function: WindowSlider
        function movingline(app, event)
            changingValue = event.Value;
            app.LowerWindowLimit = round(app.Sig_rows * changingValue(1) / 100) + 1; %need to use the event.Value, otherwise the app. properties does not update in time
            app.UpperWindowLimit = round(app.Sig_rows * changingValue(2) / 100) + 1;
            % noise addition to the original signal
            % noiseamplitude = (max(app.Sig(:)))*exp(app.NoisedBSpinner.Value/20);
            % app.Noisy_Sig = app.Sig + noiseamplitude*rand(size(app.Sig));
            cla(app.UIAxes); % if using the slides it recovers the original signal for windowing
            plot(app.UIAxes,app.Sig);
            xline(app.UIAxes, app.LowerWindowLimit); % Two vertical line to visualize the window range
            xline(app.UIAxes, app.UpperWindowLimit);
            % extra information about the range of the window
            windowsizetext = "Window Index: " + num2str(app.LowerWindowLimit-1) + " - " + num2str(app.UpperWindowLimit-1) + " " + class(app.Mod_Sig);
            app.WindowIdxLabel.Text = windowsizetext;
            app.WindowIdxLabel.Visible = "on";


        end

        % Button pushed function: AnalyzeButton
        function analyze(app, event)
            app.LowerWindowLimit = round(app.Sig_rows * app.WindowSlider.Value(1) / 100) + 1; %need to recalculate the limits to the actual base signal
            app.UpperWindowLimit = round(app.Sig_rows * app.WindowSlider.Value(2) / 100) + 1;
            z = zeros(abs(app.DelayUnitSpinner.Value),app.Sig_cols);
            [zrows,zcols] = size(z);
            if (app.DelayUnitSpinner.Value>0)
                delayedsig = app.Sig(1:end-zrows,1:zcols);
                app.Mod_Sig = [z; delayedsig];
            elseif (app.DelayUnitSpinner.Value<0) %this is not implemented yet, if the lag is negative it is become and advance
                advancesig = app.Sig(1:end-zrows-1,1:zcols);
                app.Mod_Sig = [advancesig,z];
            else
                app.Mod_Sig = app.Sig; % when no lag
            end
            app.CorrelateButton.Visible = "on";% Start button become Visible
            [mrows,mcols] = size(app.Mod_Sig);  % Modified signal size, just double check it has the same size
            sizetext = "Mod. Sig. Size: " + num2str(mrows) + " x " + num2str(mcols) + " x " + class(app.Mod_Sig);
            app.ModSigSizeLabel.Text = sizetext;
            app.ModSigSizeLabel.Visible = "on";
            windowsizetext = "Window Index: " + num2str(app.LowerWindowLimit-1) + " - " + num2str(app.UpperWindowLimit-1) + " " + class(app.Mod_Sig);
            app.WindowIdxLabel.Text = windowsizetext;
            app.WindowIdxLabel.Visible = "on";
            plot(app.UIAxes2,(app.LowerWindowLimit:app.UpperWindowLimit-1),app.Mod_Sig(app.LowerWindowLimit:app.UpperWindowLimit-1,:));
            if (app.ToggleTool.State == "on") %only plays sound if the toggle button are on
                sound(app.Mod_Sig(app.LowerWindowLimit:app.UpperWindowLimit-1), app.Fs);
            end
            % noise addition to the original signal
            noiseamplitude = (max(max(app.Sig)))*10^(app.NoisedBSpinner.Value/20); % noise amplitude
            lossyfactor = 10^(app.LossdBSpinner.Value/20); % attenuation
            % Mod_sig is delayed and can be atteunated and add noise to it
            app.Noisy_Sig = app.Mod_Sig/lossyfactor + noiseamplitude*rand(size(app.Sig));
            cla(app.UIAxes);
            plot(app.UIAxes,app.Noisy_Sig);
            xline(app.UIAxes, app.LowerWindowLimit); % Two vertical line to visualize the window range refresher
            xline(app.UIAxes, app.UpperWindowLimit);
        end

        % Callback function: ToggleTool
        function soundtoggle(app, event)
            % Icon change for controlling a sound. After a time it is
            % really annoying
            if (app.ToggleTool.State == "on")
                app.ToggleTool.Icon ="soundon.jpg";
            else
                app.ToggleTool.Icon ="soundoff.jpg";
            end
        end

        % Off callback: ToggleTool
        function soundoff(app, event)
            clear sound; % stopping the sound with a toggle button
        end

        % Button pushed function: IterateButton
        function iterate(app, event)
            app.IterateButton.Text = "Processing...";
            app.LowerWindowLimit = round(app.Sig_rows * app.WindowSlider.Value(1) / 100) + 1; %need to recalculate the limits to the actual base signal
            app.UpperWindowLimit = round(app.Sig_rows * app.WindowSlider.Value(2) / 100) + 1;
            z = zeros(abs(app.DelayUnitSpinner.Value),app.Sig_cols);
            [zrows,zcols] = size(z);
            [mrows,mcols] = size(app.Mod_Sig);  % Modified signal size, just double check it has the same size

            if (app.DelayUnitSpinner.Value>0)
                delayedsig = app.Sig(1:end-zrows,1:zcols);
                app.Mod_Sig = [z; delayedsig];
            elseif (app.DelayUnitSpinner.Value<0) %this is not implemented yet, if the lag is negative it is become and advance
                advancesig = app.Sig(1:end-zrows-1,1:zcols);
                app.Mod_Sig = [advancesig,z];
            else
                app.Mod_Sig = app.Sig; % when no lag
            end

            sizetext = "Mod. Sig. Size: " + num2str(mrows) + " x " + num2str(mcols) + " x " + class(app.Mod_Sig);
            app.ModSigSizeLabel.Text = sizetext;
            windowsizetext = "Window Index: " + num2str(app.LowerWindowLimit-1) + " - " + num2str(app.UpperWindowLimit-1) + " " + class(app.Mod_Sig);
            app.WindowIdxLabel.Text = windowsizetext;
            lossyfactor = 10^(app.LossdBSpinner.Value/20); % attenuation
            
            
            % N times generate the noise
            % MATLAB recommend preallocation for speed. 
            
            lagvector=zeros(app.IterationsSpinner.Value,1);
            corvalues=zeros(app.IterationsSpinner.Value,1);


            % noise addition to the original signal
            noiseamplitude = (max(max(app.Sig)))*10^(app.NoisedBSpinner.Value/20); % noise amplitude
            
            % Mod_sig is delayed and can be atteunated and add noise to it
            app.Noisy_Sig = app.Mod_Sig/lossyfactor + noiseamplitude*rand(size(app.Sig));
          
            [cor,lags]=xcorr(app.Noisy_Sig(:,app.Active_Ch_number), app.Sig(app.LowerWindowLimit:app.UpperWindowLimit-1,app.Active_Ch_number)); % correlations calculations for channel one, need to be parameterized
            [mval,idx] = max(abs(cor)); % index of the maximum value of the correlation    
            
           
            % Only for the first time. It is not so effective, to always
            % check the index, maybe later need more optimalization
            
            
            % Creating the folder and filename according to the timestamp
            timestamp = datetime("now","Format","ddMMyyyy_HHmmss");
            cd(app.Current_folder); %it is necessarry in the case, the directory changed during the app runtime
            mkdir(string(timestamp));
            simfilename="simulation_" + string(timestamp) + ".mat";
            new_folder = app.Current_folder + "\" + string(timestamp);
            fullroute = fullfile(new_folder,simfilename);
            axis_1_name = fullfile(new_folder,"Input_Signal.jpg");
            axis_2_name = fullfile(new_folder,"Reference_Signal.jpg");
            axis_3_name = fullfile(new_folder,"Cross_Correlation.jpg");


            cla(app.UIAxes);
            plot(app.UIAxes,app.Noisy_Sig);
            xline(app.UIAxes, app.LowerWindowLimit); % Two vertical line to visualize the window range refresher
            xline(app.UIAxes, app.UpperWindowLimit);


            plot(app.UIAxes2,(app.LowerWindowLimit:app.UpperWindowLimit-1),app.Mod_Sig(app.LowerWindowLimit:app.UpperWindowLimit-1,:));
            
            plot(app.UIAxes3,lags,cor); % plot the correlations result, need more work on this, where the max, labels, more visualization, etc...
            ytickformat(app.UIAxes3,"auto");
            ylabel(app.UIAxes3,"Correlation Coefficient");
           
             % Highlight the maximum correlation point
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3, lags(idx), mval,'ro', 'MarkerSize', 10);
            text(app.UIAxes3, lags(idx), mval, sprintf("\n               Coeff = %d",  round(mval)), "HorizontalAlignment", "left");
            text(app.UIAxes3, lags(idx), mval, sprintf("\nLag = %d               ", lags(idx)), "HorizontalAlignment", "right");
            hold(app.UIAxes3, 'off');
            

            strcorx = "Lag: " + lags(idx); % some correlation number information at the side
            strcory = "Coeff: " + round(cor(idx)); %just want to see some roundy numbers
            app.XLabel.Text = strcorx; % turning on the lag and coeff labels
            app.YLabel.Text = strcory;
            
         
            % save screenshot and axes
            exportapp(app.UIFigure, fullfile(new_folder,"appscn_str.jpg"));
            exportgraphics(app.UIAxes,axis_1_name,"Resolution",300);
            exportgraphics(app.UIAxes2,axis_2_name,"Resolution",300);
            exportgraphics(app.UIAxes3,axis_3_name,"Resolution",300);
            % exportapp(app.UIAxes2, fullfile(new_folder,"diag_2.jpg"));
            % exportapp(app.UIAxes3, fullfile(new_folder,"diag_3.jpg"));
            % exportapp(app.UIFigure, fullfile(new_folder,"appscn,jpg"));


            for i=1:app.IterationsSpinner.Value
            

            % noise addition to the original signal
            noiseamplitude = (max(max(app.Sig)))*10^(app.NoisedBSpinner.Value/20); % noise amplitude
            
            % Mod_sig is delayed and can be atteunated and add noise to it
            app.Noisy_Sig = app.Mod_Sig/lossyfactor + noiseamplitude*rand(size(app.Sig));
          
            [cor,lags]=xcorr(app.Noisy_Sig(:,app.Active_Ch_number), app.Sig(app.LowerWindowLimit:app.UpperWindowLimit-1,app.Active_Ch_number)); % correlations calculations for channel one, need to be parameterized
            [~,idx] = max(abs(cor)); % index of the maximum value of the correlation    
            lagvector(i) = lags(idx); % results stored
            corvalues(i) = round(cor(idx)); %just want to see some roundy numbers not double
            end
            app.IterateButton.Text = "Iterate";

            % saving the relevant datas
            sim_data = [(lagvector),(corvalues)];
            sim_info = audioinfo(app.SignalListBox.Value);
            sim_settings.LowerWindowLimit = app.LowerWindowLimit-1;
            sim_settings.UpperWindowLimit = app.UpperWindowLimit-1;
            sim_settings.Delay = app.DelayUnitSpinner.Value;
            sim_settings.Noise = app.NoisedBSpinner.Value;
            sim_settings.Loss = app.LossdBSpinner.Value;
            save(fullroute,"sim_data","sim_info","sim_settings");
            histogram(app.UIAxes3,sim_data(:,1),25,Normalization="percentage");
            histogram(app.UIAxes3,sim_data(:,1),25,Normalization="percentage");
            ytickformat(app.UIAxes3,"percentage");
            ylabel(app.UIAxes3,"Probability");
            % change the label
            mostfrequentvalue = string(mode(sim_data(:,1))); % most frequent bin value
            bincounts = sum(sim_data(:,1) == mode(sim_data(:,1)));
            
            app.XLabel.Text = "Most Frequent:";
            app.YLabel.Text = mostfrequentvalue + " lag " + newline  + string(bincounts) + " times ";
            exportapp(app.UIFigure, fullfile(new_folder,"appscn_end.jpg"));
            axis_4_name = fullfile(new_folder,"Histogram.jpg");
            exportgraphics(app.UIAxes3,axis_4_name,"Resolution",300);

           
        end

        % Callback function: PushTool
        function screenshot(app, event)
            % this from https://uk.mathworks.com/matlabcentral/discussions/highlights/133348-new-in-r2020b-record-snapshots-of-your-app
            % with some modification, because some function is not
            % recommended
            
            % Create a Screenshots library if not already exists, if it was
            % deleted during runtime
            if (exist("Screenshots", "dir") ~= 7)
                mkdir("Screenshots");
            end


            % create filename containing the app's figure name (spaces removed)
            % and a datetime stamp in format ddMMyyyy_HHmmss
            timestamp = datetime("now","Format","ddMMyyyy_HHmmss");
            filename = "screenshot_" + string(timestamp) + ".jpeg";
            
            % Get the app's path
            filepath = app.Current_folder + "\Screenshots";
            % Store snapshot
            exportapp(app.UIFigure, fullfile(filepath,filename));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.Tooltip = {'Sound On/Off'};
            app.ToggleTool.ClickedCallback = createCallbackFcn(app, @soundtoggle, true);
            app.ToggleTool.Icon = fullfile(pathToMLAPP, 'soundon.jpg');
            app.ToggleTool.OffCallback = createCallbackFcn(app, @soundoff, true);
            app.ToggleTool.State = 'on';
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @screenshot, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'camera.jpg');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Input Signal')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Reference Signal')
            xlabel(app.UIAxes2, 'Samples')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Cross-Correlation')
            xlabel(app.UIAxes3, 'Lag')
            ylabel(app.UIAxes3, 'Correlation Coefficient')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @correlation, true);
            app.CrossCorrelationSimulatorLabel = uilabel(app.GridLayout);
            app.CrossCorrelationSimulatorLabel.Interpreter = 'latex';
            app.CrossCorrelationSimulatorLabel.Text = 'Cross-Correlation Simulator';
            app.BaseSignalLabel = uilabel(app.GridLayout);
            app.BaseSignalLabel.Text = 'Signal';
            app.SignalListBox = uilistbox(app.GridLayout);
            app.SignalListBox.Items = {'Speech.wav', 'Bird_Song.wav', 'Cuckoo.wav', 'FreqSweep.wav', 'MatLabChirp.wav'};
            app.SignalListBox.ValueChangedFcn = createCallbackFcn(app, @load_signal, true);
            app.SignalListBox.ClickedFcn = createCallbackFcn(app, @load_signal, true);
            app.SignalListBox.DoubleClickedFcn = createCallbackFcn(app, @play_signal, true);
            app.SignalListBox.Value = 'Speech.wav';
            app.DelayUnitSpinnerLabel = uilabel(app.GridLayout);
            app.DelayUnitSpinnerLabel.Text = 'Delay (Unit)';
            app.DelayUnitSpinner = uispinner(app.GridLayout);
            app.DelayUnitSpinner.Limits = [0 30000];
            app.WindowSliderLabel = uilabel(app.GridLayout);
            app.WindowSliderLabel.Text = 'Window';
            app.WindowSlider = uislider(app.GridLayout, 'range');
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @windowlimits, true);
            app.WindowSlider.ValueChangingFcn = createCallbackFcn(app, @movingline, true);
            app.NoisedBSpinnerLabel = uilabel(app.GridLayout);
            app.NoisedBSpinnerLabel.Text = 'Noise (dB)';
            app.NoisedBSpinner = uispinner(app.GridLayout);
            app.NoisedBSpinner.Limits = [-80 100];
            app.NoisedBSpinner.Value = -80;
            app.LossdBSpinnerLabel = uilabel(app.GridLayout);
            app.LossdBSpinnerLabel.Text = 'Loss (dB)';
            app.LossdBSpinner = uispinner(app.GridLayout);
            app.LossdBSpinner.Limits = [0 20];
            app.CorrelateButton = uibutton(app.GridLayout, 'push');
            app.CorrelateButton.ButtonPushedFcn = createCallbackFcn(app, @correlation, true);
            app.CorrelateButton.Visible = 'off';
            app.CorrelateButton.Text = 'Correlate';
            app.SampleInfoLabel = uilabel(app.GridLayout);
            app.SampleInfoLabel.Interpreter = 'latex';
            app.SampleInfoLabel.Text = 'Sample Info:';
            app.SampleDataLabel = uilabel(app.GridLayout);
            app.SampleDataLabel.Interpreter = 'latex';
            app.SampleDataLabel.Text = 'Sample Data:';
            app.ModSigSizeLabel = uilabel(app.GridLayout);
            app.ModSigSizeLabel.Text = 'Mod. Sig. Size:';
            app.AnalyzeButton = uibutton(app.GridLayout, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @analyze, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.WindowIdxLabel = uilabel(app.GridLayout);
            app.WindowIdxLabel.Text = 'Window Index:';
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Visible = 'off';
            app.YLabel.Text = 'Y:';
            app.IterateButton = uibutton(app.GridLayout, 'push');
            app.IterateButton.ButtonPushedFcn = createCallbackFcn(app, @iterate, true);
            app.IterateButton.Visible = 'off';
            app.IterateButton.Text = 'Iterate';
            app.IterationsSpinnerLabel = uilabel(app.GridLayout);
            app.IterationsSpinnerLabel.Visible = 'off';
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.GridLayout);
            app.IterationsSpinner.Limits = [1 10000];
            app.IterationsSpinner.Visible = 'off';
            app.IterationsSpinner.Value = 200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crosscorrelation_PS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end